classdef path_find < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GaugeLabel                  matlab.ui.control.Label
        Gauge                       matlab.ui.control.Gauge
        UIAxes                      matlab.ui.control.UIAxes
        PathFindButton              matlab.ui.control.Button
        obstacle_selectionDropDownLabel  matlab.ui.control.Label
        obstacle_selectionDropDown  matlab.ui.control.DropDown
        EditFieldLabel              matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PathFindButton
        function PathFindButtonPushed(app, event)
            tic
            path_finding
            toc
            h1 = msgbox({'Total path Finding Time in seconds.........' '' num2str(toc)} ,'Info');
            handles.path_time.String = num2str((floor(toc)));
            uiwait(h1,10)
        end

        % Value changed function: obstacle_selectionDropDown
        function obstacle_selectionDropDownValueChanged(app, event)
            value = app.obstacle_selectionDropDown.Value;
            a = get(handles.obstacle_selection,'Value');
            
            [Obstacle] = obstacle(a);
            init_goal
            %     [init , goal ] = init_goal()
            plot_obstacle( init,goal,r_init, goal_rad,Obstacle )
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PathFindButton = uibutton(app.UIFigure, 'push');
            app.PathFindButton.ButtonPushedFcn = createCallbackFcn(app, @PathFindButtonPushed, true);
            app.PathFindButton.Text = 'Path Find';
            app.obstacle_selectionDropDownLabel = uilabel(app.UIFigure);
            app.obstacle_selectionDropDownLabel.Text = 'obstacle_selection';
            app.obstacle_selectionDropDown = uidropdown(app.UIFigure);
            app.obstacle_selectionDropDown.ValueChangedFcn = createCallbackFcn(app, @obstacle_selectionDropDownValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 10];
            app.EditField.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = path_find

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end